classdef FDT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        PlotButton                  matlab.ui.control.Button
        FlowDirectionFileButton     matlab.ui.control.Button
        FlowDirectionText           matlab.ui.control.EditField
        GageLocationFileButton      matlab.ui.control.Button
        GageLocationText            matlab.ui.control.EditField
        RiverFileButton             matlab.ui.control.Button
        RiverText                   matlab.ui.control.EditField
        BasinBoundaryFileButton     matlab.ui.control.Button
        BasinBoundaryText           matlab.ui.control.EditField
        InputsLabel                 matlab.ui.control.Label
        FlowDirectionToolkitLabel   matlab.ui.control.Label
        SpecifycoordinatesystemButtonGroup  matlab.ui.container.ButtonGroup
        GeographicButton            matlab.ui.control.RadioButton
        ProjectedButton             matlab.ui.control.RadioButton
        PlotgridButtonGroup         matlab.ui.container.ButtonGroup
        YesButton                   matlab.ui.control.RadioButton
        NofasterButton              matlab.ui.control.RadioButton
        CheckButton                 matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        ModifyFlowDirectionsButton  matlab.ui.control.Button
        CalibrateButton             matlab.ui.control.Button
        y_trueEditFieldLabel        matlab.ui.control.Label
        y_trueEditField             matlab.ui.control.NumericEditField
        ControlsLabel               matlab.ui.control.Label
        SaveLabel                   matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        SaveName                    matlab.ui.control.EditField
        OutputformatButtonGroup     matlab.ui.container.ButtonGroup
        ASCIIButton                 matlab.ui.control.RadioButton
        GeotiffButton               matlab.ui.control.RadioButton
        FlowdirectionconventionButtonGroup  matlab.ui.container.ButtonGroup
        VICButton                   matlab.ui.control.RadioButton
        GRASSButton                 matlab.ui.control.RadioButton
        ArcMAPButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        fdirfile;
        bbfile;
        rivfile;
        gagefile;
        projflag;
        plotgrid = 1;
        fontsize = 20;
        X;
        Y;
        fdir;
        loc;
        R;
        res;
        DialogApp; % dialog box app
        xx = zeros(0,1);
        yy = zeros(0,1);
        replacenum = zeros(0,1);
        ind;
        gage;
        bb;
        rivs;
        y_offset; % used to make sure that the y-coordinate is accurate
        y_hat;
        y_true;
        calibrate_flag = 0; % allows calibration
        x; % x and y coordinates used for saving as ASCII file 
        y; % these are vectors of grid cell centers
    end
    
    methods (Access = public)
        
        function [xx, yy, replacenum] = append_gridcell(app, xx, yy, replacenum)
            app.xx = [app.xx; xx];
            app.yy = [app.yy; yy];
            app.replacenum = [app.replacenum; replacenum];
            disp(app.xx)
            disp(app.yy)
            disp(app.replacenum)
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            crs = app.SpecifycoordinatesystemButtonGroup.SelectedObject;
            pg = app.PlotgridButtonGroup.SelectedObject;
            
            if strcmp(crs.Text, 'Geographic')
                app.projflag = 0;
            else 
                app.projflag = 1;
            end
            
            if strcmp(pg.Text, 'Yes')
                app.plotgrid = 1;
            else 
                app.plotgrid = 0;
            end            
            
            [app.fdir, app.res, app.X, app.Y, app.ind, app.gage, app.bb, app.rivs, app.R, app.x, app.y] = ...
                correct_flowdir(app.fdirfile, app.bbfile, app.rivfile, ...
                app.gagefile, app.projflag);

            plotflowdir(app.fdir, app.res, app.X, app.Y, app.ind, ...
                app.gage, app.bb, app.rivs, app.plotgrid, app.UIAxes, app.fontsize);
            
%             axis(app.UIAxes, 'tight')
            
            %             myplot(app.fdirfile, app.UIAxes)
            
        end

        % Button pushed function: FlowDirectionFileButton
        function FlowDirectionFileButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd, '*.tif;*.asc'),'select file');
            app.fdirfile = fullfile(path, file);
            app.FlowDirectionText.Value = app.fdirfile;
        end

        % Button pushed function: BasinBoundaryFileButton
        function BasinBoundaryFileButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd, '*.shp'));
            app.bbfile = fullfile(path, file);
            app.BasinBoundaryText.Value = app.bbfile;
        end

        % Button pushed function: GageLocationFileButton
        function GageLocationFileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            app.gagefile = fullfile(path, file);
            app.GageLocationText.Value = app.gagefile;            
        end

        % Button pushed function: RiverFileButton
        function RiverFileButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd, '*.shp'));
            app.rivfile = fullfile(path, file);
            app.RiverText.Value = app.rivfile;    
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            check_cells(app.UIAxes, app.X, app.Y, app.fdir);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.xx = zeros(0,1);
            app.yy = zeros(0,1);
            app.replacenum = zeros(0,1);
            cla(app.UIAxes);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            temp = app.UIAxes.CurrentPoint; 
            app.loc = [temp(1,1) temp(1,2)]; % (x,y) coordinates
            
            if app.calibrate_flag == 1
                app.y_hat = app.loc(2);
                app.y_offset = app.y_true - app.y_hat;
                app.calibrate_flag = 2;
                
            elseif app.calibrate_flag == 2 % calibrated
            
                % Adjust the value of the clicked point using the calibrate
                % y_offset value. Must be done to get accurate position.
                app.loc(2) = app.loc(2) + app.y_offset;                
                    
                % Check whether the clicked point is in the domain
                max_x = max(app.X(:));
                min_x = min(app.X(:));
                max_y = max(app.Y(:));
                min_y = min(app.Y(:));          
                if (app.loc(1) >= min_x && app.loc(1) <= max_x) && ...
                        (app.loc(2) >= min_y && app.loc(2) <= max_y)
                    draw_box(app.UIAxes, app.loc, app.X, app.Y);
                    app.DialogApp = dialog_box(app, app.loc);                
                end
            
            else
                disp('need to calibrate')
            end
            disp(app.loc)
        end

        % Button pushed function: ModifyFlowDirectionsButton
        function ModifyFlowDirectionsButtonPushed(app, event)
            
            % Modifies the flow directions using xx, yy, replacenum
            app.fdir = modify_flowdir(app.fdir, app.R, app.res, app.xx, ...
                app.yy, app.replacenum, app.projflag);
            
            % Regenerates the plot with the modified flow directions
            cla(app.UIAxes)
            plotflowdir(app.fdir, app.res, app.X, app.Y, app.ind, ...
                app.gage, app.bb, app.rivs, app.plotgrid, app.UIAxes, app.fontsize);
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if app.calibrate_flag ~= 1
                app.calibrate_flag = 1;
                app.y_true = app.y_trueEditField.Value;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            saveformat = app.OutputformatButtonGroup.SelectedObject.Text;
            fd_convention = app.FlowdirectionconventionButtonGroup.SelectedObject.Text;
%             savename = app.SaveName.Value;
            
            [file, path] = uiputfile(fullfile(pwd, '*.tif;*.asc'),'select file');
            savename = fullfile(path, file);
            app.SaveName.Value = savename;                
            
            switch fd_convention
                case 'ArcMAP'
                    fd_copy = app.fdir;
                    fd_copy(app.fdir == 1) = 64;
                    fd_copy(app.fdir == 2) = 128;
                    fd_copy(app.fdir == 3) = 1;
                    fd_copy(app.fdir == 4) = 2;
                    fd_copy(app.fdir == 5) = 4;
                    fd_copy(app.fdir == 6) = 8;
                    fd_copy(app.fdir == 7) = 16;
                    fd_copy(app.fdir == 8) = 32;         
                    app.fdir = fd_copy;                    
                case 'GRASS'
                    fd_copy = app.fdir;
                    fd_copy(app.fdir == 1) = 2;
                    fd_copy(app.fdir == 2) = 1;
                    fd_copy(app.fdir == 3) = 8;
                    fd_copy(app.fdir == 4) = 7;
                    fd_copy(app.fdir == 5) = 6;
                    fd_copy(app.fdir == 6) = 5;
                    fd_copy(app.fdir == 7) = 4;
                    fd_copy(app.fdir == 8) = 3;         
                    app.fdir = fd_copy;
                case 'VIC'
                    disp('Already in VIC convention. No need to convert.')
            end
            
%             saveformat
            
            if strcmp(saveformat, 'ASCII')
                app.fdir(isnan(app.fdir)) = 0;
                app.fdir = single(app.fdir);
                disp('Saving ASCII file')
                arcgridwrite(savename, app.X, app.Y, flipud(uint8(app.fdir)));
            elseif strcmp(saveformat, 'Geotiff')
                disp('Saving Geotiff file')
                geotiffwrite(savename, app.fdir, app.R);
            end 
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Flow Directions')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FlowDirectionFileButton = uibutton(app.UIFigure, 'push');
            app.FlowDirectionFileButton.ButtonPushedFcn = createCallbackFcn(app, @FlowDirectionFileButtonPushed, true);
            app.FlowDirectionFileButton.Text = 'Flow Direction File';
            app.FlowDirectionText = uieditfield(app.UIFigure, 'text');
            app.GageLocationFileButton = uibutton(app.UIFigure, 'push');
            app.GageLocationFileButton.ButtonPushedFcn = createCallbackFcn(app, @GageLocationFileButtonPushed, true);
            app.GageLocationFileButton.Text = 'Gage Location File';
            app.GageLocationText = uieditfield(app.UIFigure, 'text');
            app.RiverFileButton = uibutton(app.UIFigure, 'push');
            app.RiverFileButton.ButtonPushedFcn = createCallbackFcn(app, @RiverFileButtonPushed, true);
            app.RiverFileButton.Text = 'River File';
            app.RiverText = uieditfield(app.UIFigure, 'text');
            app.BasinBoundaryFileButton = uibutton(app.UIFigure, 'push');
            app.BasinBoundaryFileButton.ButtonPushedFcn = createCallbackFcn(app, @BasinBoundaryFileButtonPushed, true);
            app.BasinBoundaryFileButton.Text = 'Basin Boundary File';
            app.BasinBoundaryText = uieditfield(app.UIFigure, 'text');
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.FlowDirectionToolkitLabel = uilabel(app.UIFigure);
            app.FlowDirectionToolkitLabel.Text = 'Flow Direction Toolkit';
            app.SpecifycoordinatesystemButtonGroup = uibuttongroup(app.UIFigure);
            app.SpecifycoordinatesystemButtonGroup.Title = 'Specify coordinate system';
            app.GeographicButton = uiradiobutton(app.SpecifycoordinatesystemButtonGroup);
            app.GeographicButton.Text = 'Geographic';
            app.GeographicButton.Value = true;
            app.ProjectedButton = uiradiobutton(app.SpecifycoordinatesystemButtonGroup);
            app.ProjectedButton.Text = 'Projected';
            app.PlotgridButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotgridButtonGroup.Title = 'Plot grid?';
            app.YesButton = uiradiobutton(app.PlotgridButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NofasterButton = uiradiobutton(app.PlotgridButtonGroup);
            app.NofasterButton.Text = 'No (faster)';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ModifyFlowDirectionsButton = uibutton(app.UIFigure, 'push');
            app.ModifyFlowDirectionsButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyFlowDirectionsButtonPushed, true);
            app.ModifyFlowDirectionsButton.Text = 'Modify Flow Directions';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.y_trueEditFieldLabel = uilabel(app.UIFigure);
            app.y_trueEditFieldLabel.Text = 'y_true';
            app.y_trueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ControlsLabel = uilabel(app.UIFigure);
            app.ControlsLabel.Text = 'Controls';
            app.SaveLabel = uilabel(app.UIFigure);
            app.SaveLabel.Text = 'Save';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveName = uieditfield(app.UIFigure, 'text');
            app.OutputformatButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputformatButtonGroup.Title = 'Output format';
            app.ASCIIButton = uiradiobutton(app.OutputformatButtonGroup);
            app.ASCIIButton.Text = 'ASCII';
            app.ASCIIButton.Value = true;
            app.GeotiffButton = uiradiobutton(app.OutputformatButtonGroup);
            app.GeotiffButton.Text = 'Geotiff';
            app.FlowdirectionconventionButtonGroup = uibuttongroup(app.UIFigure);
            app.FlowdirectionconventionButtonGroup.Title = 'Flow direction convention';
            app.VICButton = uiradiobutton(app.FlowdirectionconventionButtonGroup);
            app.VICButton.Text = 'VIC';
            app.VICButton.Value = true;
            app.GRASSButton = uiradiobutton(app.FlowdirectionconventionButtonGroup);
            app.GRASSButton.Text = 'GRASS';
            app.ArcMAPButton = uiradiobutton(app.FlowdirectionconventionButtonGroup);
            app.ArcMAPButton.Text = 'ArcMAP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FDT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end