classdef dialog_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OKButton                     matlab.ui.control.Button
        ChooseadirectionButtonGroup  matlab.ui.container.ButtonGroup
        NButton                      matlab.ui.control.RadioButton
        NEButton                     matlab.ui.control.RadioButton
        EButton                      matlab.ui.control.RadioButton
        NWButton                     matlab.ui.control.RadioButton
        WButton                      matlab.ui.control.RadioButton
        SWButton                     matlab.ui.control.RadioButton
        SButton                      matlab.ui.control.RadioButton
        SEButton                     matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp; % Description
        xx; % x coordinate of the current grid cell
        yy; % y coordinate of the selected grid cell
        replacenum; %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, loc)
            app.CallingApp = mainapp;
            app.xx = loc(1);
            app.yy = loc(2);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
                        
            dir1 = app.ChooseadirectionButtonGroup.SelectedObject;
            
            switch dir1.Text % UW routing model convention
                case 'N'
                    app.replacenum = 1;
                case 'NE'
                    app.replacenum = 2;
                case 'E'
                    app.replacenum = 3;
                case 'SE'
                    app.replacenum = 4;
                case 'S'
                    app.replacenum = 5;
                case 'SW'
                    app.replacenum = 6;
                case 'W'
                    app.replacenum = 7;
                case 'NW'
                    app.replacenum = 8;             
            end
            
            append_gridcell(app.CallingApp, app.xx, app.yy, app.replacenum);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ChooseadirectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseadirectionButtonGroup.Title = 'Choose a direction';
            app.NButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.NButton.Text = 'N';
            app.NButton.Value = true;
            app.NEButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.NEButton.Text = 'NE';
            app.EButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.EButton.Text = 'E';
            app.NWButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.NWButton.Text = 'NW';
            app.WButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.WButton.Text = 'W';
            app.SWButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.SWButton.Text = 'SW';
            app.SButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.SButton.Text = 'S';
            app.SEButton = uiradiobutton(app.ChooseadirectionButtonGroup);
            app.SEButton.Text = 'SE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_box(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end